classdef april9_weight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WeightEditFieldLabel           matlab.ui.control.Label
        WeightEditField                matlab.ui.control.NumericEditField
        ConvertedWeightEditFieldLabel  matlab.ui.control.Label
        ConvertedWeightEditField       matlab.ui.control.NumericEditField
        UnitDropDownLabel              matlab.ui.control.Label
        UnitDropDown                   matlab.ui.control.DropDown
        CalculateButton                matlab.ui.control.Button
        ConvertedWeightLabel           matlab.ui.control.Label
        ConvertedWeightGauge           matlab.ui.control.LinearGauge
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            val = app.UnitDropDown.Value; % See DropDown
            weight = app.WeightEditField.Value;
           
            
            switch val
                case 'Pounds'
                 new_weight = 0.454*weight;
                 app.ConvertedWeightGauge.Value = new_weight;
                 app.ConvertedWeightEditField.Value = new_weight;
                case 'Kilograms'
                 new_weight = 2.205*weight; 
                 app.ConvertedWeightGauge.Value = new_weight;
                 app.ConvertedWeightEditField.Value = new_weight;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WeightEditFieldLabel = uilabel(app.UIFigure);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConvertedWeightEditFieldLabel = uilabel(app.UIFigure);
            app.ConvertedWeightEditFieldLabel.Text = 'Converted Weight';
            app.ConvertedWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.UIFigure);
            app.UnitDropDown.Items = {'Pounds', 'Kilograms'};
            app.UnitDropDown.Value = 'Pounds';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConvertedWeightLabel = uilabel(app.UIFigure);
            app.ConvertedWeightLabel.Text = 'Converted Weight';
            app.ConvertedWeightGauge = uigauge(app.UIFigure, 'linear');
            app.ConvertedWeightGauge.Limits = [0 1000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = april9_weight

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end