classdef beach_yippee < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image1DropDownLabel   matlab.ui.control.Label
        Image1DropDown        matlab.ui.control.DropDown
        Image2Label           matlab.ui.control.Label
        Image2DropDown        matlab.ui.control.DropDown
        LGreenEditFieldLabel  matlab.ui.control.Label
        LGreenEditField       matlab.ui.control.NumericEditField
        UGreenEditFieldLabel  matlab.ui.control.Label
        UGreenEditField       matlab.ui.control.NumericEditField
        MASKButton            matlab.ui.control.Button
        Image                 matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MASKButton
        function MASKButtonPushed(app, event)
            val1 = app.Image1DropDown.Value; % See DropDown
            val2 = app.Image2DropDown.Value; % See Dropdown
            LG = app.LGreenEditField.Value;
            UG = app.UGreenEditField.Value;
    
             switch val1
                 case 'Beach'
                 fc=imread('background.jpg'); %beach
             end
             switch val2
                 case 'Yippee'
                    fc1=imread('yippee.jpg'); %yippee
             end
             greenfc1=fc1(:,:,2); %mask on green layer yippee
             mask_g=(LG<greenfc1)&(greenfc1<UG);
             
             maskfc1=cat(3,mask_g,mask_g,mask_g);
             yippee_beach=fc1; %assign yippee to yippee_beach
               
             yippee_beach(maskfc1)=fc(maskfc1);  
             figure(202) %display result
                 
             a=image(yippee_beach)
             imshow(a)=app.Image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image1DropDownLabel = uilabel(app.UIFigure);
            app.Image1DropDownLabel.Text = 'Image1';
            app.Image1DropDown = uidropdown(app.UIFigure);
            app.Image1DropDown.Items = {'Beach'};
            app.Image1DropDown.Value = 'Beach';
            app.Image2Label = uilabel(app.UIFigure);
            app.Image2Label.Text = 'Image2';
            app.Image2DropDown = uidropdown(app.UIFigure);
            app.Image2DropDown.Items = {'Yippee'};
            app.Image2DropDown.Value = 'Yippee';
            app.LGreenEditFieldLabel = uilabel(app.UIFigure);
            app.LGreenEditFieldLabel.Text = 'L Green';
            app.LGreenEditField = uieditfield(app.UIFigure, 'numeric');
            app.UGreenEditFieldLabel = uilabel(app.UIFigure);
            app.UGreenEditFieldLabel.Text = 'U Green';
            app.UGreenEditField = uieditfield(app.UIFigure, 'numeric');
            app.MASKButton = uibutton(app.UIFigure, 'push');
            app.MASKButton.ButtonPushedFcn = createCallbackFcn(app, @MASKButtonPushed, true);
            app.MASKButton.Text = 'MASK';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beach_yippee

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end