classdef integrate_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FunctionDropDownLabel   matlab.ui.control.Label
        FunctionDropDown        matlab.ui.control.DropDown
        LLEditFieldLabel        matlab.ui.control.Label
        LLEditField             matlab.ui.control.NumericEditField
        ULEditFieldLabel        matlab.ui.control.Label
        ULEditField             matlab.ui.control.NumericEditField
        IntegrateButton         matlab.ui.control.Button
        ResultEditFieldLabel    matlab.ui.control.Label
        ResultEditField         matlab.ui.control.NumericEditField
        UIAxes                  matlab.ui.control.UIAxes
        IntervalEditFieldLabel  matlab.ui.control.Label
        IntervalEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IntegrateButton
        function IntegrateButtonPushed(app, event)
          val = app.FunctionDropDown.Value; % See DropDown
          LL = app.LLEditField.Value;
          UL = app.ULEditField.Value;
          Interval = app.IntervalEditField.Value;
           switch val
                case '5x^2 +5'
                    F1=@(x) 5.*x.^2 + 5;
                    eval_x=[LL:Interval:UL];
                    npts=length(eval_x);
                    for i=1:1:npts
                        output(i)=integral(F1,LL,eval_x(i));
                    end
                 plot(app.UIAxes, eval_x, output,'b')
                 xx=output(npts);
                 app.ResultEditField.Value = xx;
                
                case 'e^(x+2)'
                 F1=@(x) exp(x+2);
                 eval_x=[LL:Interval:UL];
                    npts=length(eval_x);
                    for i=1:1:npts
                        output(i)=integral(F1,LL,eval_x(i));
                    end
                 plot(app.UIAxes, eval_x, output,'b')
              
                 xx=output(npts);
               
                 app.ResultEditField.Value = xx;
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'5x^2 +5', 'e^(x+2)'};
            app.FunctionDropDown.Value = '5x^2 +5';
            app.LLEditFieldLabel = uilabel(app.UIFigure);
            app.LLEditFieldLabel.Text = 'LL';
            app.LLEditField = uieditfield(app.UIFigure, 'numeric');
            app.ULEditFieldLabel = uilabel(app.UIFigure);
            app.ULEditFieldLabel.Text = 'UL';
            app.ULEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntegrateButton = uibutton(app.UIFigure, 'push');
            app.IntegrateButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrateButtonPushed, true);
            app.IntegrateButton.Text = 'Integrate';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Integral Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Function')
            app.IntervalEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integrate_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end