classdef integrate_slider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FunctionDropDownLabel  matlab.ui.control.Label
        FunctionDropDown       matlab.ui.control.DropDown
        IntegrateButton        matlab.ui.control.Button
        ResultGaugeLabel       matlab.ui.control.Label
        ResultGauge            matlab.ui.control.Gauge
        ResultEditFieldLabel   matlab.ui.control.Label
        ResultEditField        matlab.ui.control.NumericEditField
        LLSliderLabel          matlab.ui.control.Label
        LLSlider               matlab.ui.control.Slider
        ULSliderLabel          matlab.ui.control.Label
        ULSlider               matlab.ui.control.Slider
        LLEditFieldLabel       matlab.ui.control.Label
        LLEditField            matlab.ui.control.NumericEditField
        ULEditFieldLabel       matlab.ui.control.Label
        ULEditField            matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IntegrateButton
        function IntegrateButtonPushed(app, event)
         val = app.FunctionDropDown.Value; % See DropDown
          LL = app.LLSlider.Value;
          UL = app.ULSlider.Value;
                      switch val
                case '5x^2 +5'
                    F1=@(x) 5.*x.^2  + 5;
                 xx= integral(F1,LL,UL);
                 app.ResultGauge.Value = xx;
                 app.ResultEditField.Value = xx;
                  
                case 'e^(x+2)'
                    F1=@(x) exp(x+2);
                 xx= integral(F1,LL,UL);
                 app.ResultGauge.Value = xx;
                 app.ResultEditField.Value = xx;
                      end
        end

        % Value changing function: LLSlider
        function LLSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LLEditField.Value=changingValue;
        end

        % Value changing function: ULSlider
        function ULSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ULEditField.Value=changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'5x^2 +5', 'e^(x+2)'};
            app.FunctionDropDown.Value = '5x^2 +5';
            app.IntegrateButton = uibutton(app.UIFigure, 'push');
            app.IntegrateButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrateButtonPushed, true);
            app.IntegrateButton.Text = 'Integrate';
            app.ResultGaugeLabel = uilabel(app.UIFigure);
            app.ResultGaugeLabel.Text = 'Result';
            app.ResultGauge = uigauge(app.UIFigure, 'circular');
            app.ResultGauge.Limits = [-2000 2000];
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultEditField.ValueDisplayFormat = '%.3f';
            app.LLSliderLabel = uilabel(app.UIFigure);
            app.LLSliderLabel.Text = 'LL';
            app.LLSlider = uislider(app.UIFigure);
            app.LLSlider.Limits = [-10 10];
            app.LLSlider.ValueChangingFcn = createCallbackFcn(app, @LLSliderValueChanging, true);
            app.ULSliderLabel = uilabel(app.UIFigure);
            app.ULSliderLabel.Text = 'UL';
            app.ULSlider = uislider(app.UIFigure);
            app.ULSlider.Limits = [-10 10];
            app.ULSlider.ValueChangingFcn = createCallbackFcn(app, @ULSliderValueChanging, true);
            app.LLEditFieldLabel = uilabel(app.UIFigure);
            app.LLEditFieldLabel.Text = 'LL';
            app.LLEditField = uieditfield(app.UIFigure, 'numeric');
            app.ULEditFieldLabel = uilabel(app.UIFigure);
            app.ULEditFieldLabel.Text = 'UL';
            app.ULEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integrate_slider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end