classdef integrate_with_slider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FunctionDropDownLabel  matlab.ui.control.Label
        FunctionDropDown       matlab.ui.control.DropDown
        ResultGaugeLabel       matlab.ui.control.Label
        ResultGauge            matlab.ui.control.Gauge
        LLSliderLabel          matlab.ui.control.Label
        LLSlider               matlab.ui.control.Slider
        LLEditFieldLabel       matlab.ui.control.Label
        LLEditField            matlab.ui.control.NumericEditField
        ULSliderLabel          matlab.ui.control.Label
        ULSlider               matlab.ui.control.Slider
        ULEditFieldLabel       matlab.ui.control.Label
        ULEditField            matlab.ui.control.NumericEditField
        ResultEditFieldLabel   matlab.ui.control.Label
        ResultEditField        matlab.ui.control.NumericEditField
        IntegrateButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: LLSlider
        function LLSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LLEditField.Value=changingValue;
        end

        % Value changing function: ULSlider
        function ULSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ULEditField.Value=changingValue;
        end

        % Button pushed function: IntegrateButton
        function IntegrateButtonPushed(app, event)
            val=app.FunctionDropDown.Value;
            LL=app.LLSlider.Value;
            UL=app.ULSlider.Value;
            switch val
                case '5x^2+5'
                    Func=@(x) 5.*x.^2 + 5;
                    tot=integral(Func,LL,UL);
                    app.ResultGauge.Value = tot;
                    app.ResultEditField.Value = tot;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'5x^2+5'};
            app.FunctionDropDown.Value = '5x^2+5';
            app.ResultGaugeLabel = uilabel(app.UIFigure);
            app.ResultGaugeLabel.Text = 'Result';
            app.ResultGauge = uigauge(app.UIFigure, 'circular');
            app.LLSliderLabel = uilabel(app.UIFigure);
            app.LLSliderLabel.Text = 'LL';
            app.LLSlider = uislider(app.UIFigure);
            app.LLSlider.Limits = [-10 10];
            app.LLSlider.ValueChangingFcn = createCallbackFcn(app, @LLSliderValueChanging, true);
            app.LLEditFieldLabel = uilabel(app.UIFigure);
            app.LLEditFieldLabel.Text = 'LL';
            app.LLEditField = uieditfield(app.UIFigure, 'numeric');
            app.ULSliderLabel = uilabel(app.UIFigure);
            app.ULSliderLabel.Text = 'UL';
            app.ULSlider = uislider(app.UIFigure);
            app.ULSlider.Limits = [-10 10];
            app.ULSlider.ValueChangingFcn = createCallbackFcn(app, @ULSliderValueChanging, true);
            app.ULEditFieldLabel = uilabel(app.UIFigure);
            app.ULEditFieldLabel.Text = 'UL';
            app.ULEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntegrateButton = uibutton(app.UIFigure, 'push');
            app.IntegrateButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrateButtonPushed, true);
            app.IntegrateButton.Text = 'Integrate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integrate_with_slider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end