classdef mh_distance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        YardsEditFieldLabel     matlab.ui.control.Label
        YardsEditField          matlab.ui.control.NumericEditField
        MetersEditFieldLabel    matlab.ui.control.Label
        MetersEditField         matlab.ui.control.NumericEditField
        YardsButton             matlab.ui.control.Button
        YardsGaugeLabel         matlab.ui.control.Label
        YardsGauge              matlab.ui.control.LinearGauge
        MeterstoYardsLabel      matlab.ui.control.Label
        YardstoMetersLabel      matlab.ui.control.Label
        YardsEditField_2Label   matlab.ui.control.Label
        YardsEditField_2        matlab.ui.control.NumericEditField
        MetersEditField_2Label  matlab.ui.control.Label
        MetersEditField_2       matlab.ui.control.NumericEditField
        MetersGaugeLabel        matlab.ui.control.Label
        MetersGauge             matlab.ui.control.LinearGauge
        MetersButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: YardsButton
        function YardsButtonPushed(app, event)
            app.YardsEditField.Value = (1/.9144)*(app.MetersEditField.Value);
            app.YardsGauge.Value = app.YardsEditField.Value;
        end

        % Button pushed function: MetersButton
        function MetersButtonPushed(app, event)
            app.MetersEditField_2.Value = (.9144)*(app.YardsEditField_2.Value);
            app.MetersGauge.Value = app.MetersEditField_2.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.YardsEditFieldLabel = uilabel(app.UIFigure);
            app.YardsEditFieldLabel.Text = 'Yards';
            app.YardsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MetersEditFieldLabel = uilabel(app.UIFigure);
            app.MetersEditFieldLabel.Text = 'Meters';
            app.MetersEditField = uieditfield(app.UIFigure, 'numeric');
            app.YardsButton = uibutton(app.UIFigure, 'push');
            app.YardsButton.ButtonPushedFcn = createCallbackFcn(app, @YardsButtonPushed, true);
            app.YardsButton.Text = 'Yards';
            app.YardsGaugeLabel = uilabel(app.UIFigure);
            app.YardsGaugeLabel.Text = 'Yards';
            app.YardsGauge = uigauge(app.UIFigure, 'linear');
            app.YardsGauge.Limits = [0 150];
            app.MeterstoYardsLabel = uilabel(app.UIFigure);
            app.MeterstoYardsLabel.Text = 'Meters to Yards';
            app.YardstoMetersLabel = uilabel(app.UIFigure);
            app.YardstoMetersLabel.Text = 'Yards to Meters';
            app.YardsEditField_2Label = uilabel(app.UIFigure);
            app.YardsEditField_2Label.Text = 'Yards';
            app.YardsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MetersEditField_2Label = uilabel(app.UIFigure);
            app.MetersEditField_2Label.Text = 'Meters';
            app.MetersEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MetersGaugeLabel = uilabel(app.UIFigure);
            app.MetersGaugeLabel.Text = 'Meters';
            app.MetersGauge = uigauge(app.UIFigure, 'linear');
            app.MetersGauge.Limits = [0 150];
            app.MetersButton = uibutton(app.UIFigure, 'push');
            app.MetersButton.ButtonPushedFcn = createCallbackFcn(app, @MetersButtonPushed, true);
            app.MetersButton.Text = 'Meters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mh_distance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end