classdef mplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Function1DropDownLabel   matlab.ui.control.Label
        Function1DropDown        matlab.ui.control.DropDown
        LBoundEditFieldLabel     matlab.ui.control.Label
        LBoundEditField          matlab.ui.control.NumericEditField
        UBoundEditFieldLabel     matlab.ui.control.Label
        UBoundEditField          matlab.ui.control.NumericEditField
        PlotButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        IncrementEditFieldLabel  matlab.ui.control.Label
        IncrementEditField       matlab.ui.control.NumericEditField
        Function2DropDownLabel   matlab.ui.control.Label
        Function2DropDown        matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
         app.UIAxes.cla; %clears my plot(s) from before
         val1 = app.Function1DropDown.Value; % See DropDown
         val2 = app.Function2DropDown.Value; % See Dropdown
         LB = app.LBoundEditField.Value;
         UB = app.UBoundEditField.Value;
         Increment = app.IncrementEditField.Value;
            switch val1
                case '5x^2 +5'
                    F1=@(x) 5.*x.^2  + 5;
                    eval_x=[LB:Increment:UB];
                    npts=length(eval_x);
                    for i=1:1:npts
                        output1(i)= F1(eval_x(i));
                    end
                 case 'e^(x+2)'
                 F1=@(x) exp(x+2);
                 eval_x=[LB:Increment:UB];
                    npts=length(eval_x);
                    for i=1:1:npts
                        output1(i)= F1(eval_x(i));
                    end
            end
            
            switch val2
                case '3x^3 -8'
                    F2=@(x) 3.*x.^3  - 8;
                    eval_x=[LB:Increment:UB];
                    npts=length(eval_x);
                    for i=1:1:npts
                        output2(i)= F2(eval_x(i));
                    end
                 case 'e^(2x-1)'
                 F1=@(x) exp(2.*x -1);
                 eval_x=[LB:Increment:UB];
                    npts=length(eval_x);
                    for i=1:1:npts
                        output2(i)= F1(eval_x(i));
                    end
            end
                    hold(app.UIAxes,'on') %multiple plots coming
                    plot(app.UIAxes, eval_x,output1,'b')
                    plot(app.UIAxes,eval_x,output2,'r')
                    hold(app.UIAxes,'off') %we are finished plotting
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Function1DropDownLabel = uilabel(app.UIFigure);
            app.Function1DropDownLabel.Text = 'Function1';
            app.Function1DropDown = uidropdown(app.UIFigure);
            app.Function1DropDown.Items = {'5x^2 +5', 'e^(x+2)'};
            app.Function1DropDown.Value = '5x^2 +5';
            app.LBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LBoundEditFieldLabel.Text = 'LBound';
            app.LBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LBoundEditField.Value = 2;
            app.UBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UBoundEditFieldLabel.Text = 'UBound';
            app.UBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.UBoundEditField.Value = 6;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Function')
            app.IncrementEditFieldLabel = uilabel(app.UIFigure);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncrementEditField.Value = 0.01;
            app.Function2DropDownLabel = uilabel(app.UIFigure);
            app.Function2DropDownLabel.Text = 'Function 2';
            app.Function2DropDown = uidropdown(app.UIFigure);
            app.Function2DropDown.Items = {'3x^3 -8', 'e^(2x-1)'};
            app.Function2DropDown.Value = '3x^3 -8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end